classdef elevator2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ElePanel                   matlab.ui.container.Panel
        CurrentFloorTextAreaLabel  matlab.ui.control.Label
        CurrentFloor               matlab.ui.control.TextArea
        DirectionTextAreaLabel     matlab.ui.control.Label
        Direction                  matlab.ui.control.TextArea
        Floor3Check                matlab.ui.control.CheckBox
        Floor2Check                matlab.ui.control.CheckBox
        Floor1Check                matlab.ui.control.CheckBox
        OpenDoor                   matlab.ui.control.Button
        DoorStateTextAreaLabel     matlab.ui.control.Label
        DoorState                  matlab.ui.control.TextArea
        CloseDoor                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        inside_panel
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % app.inside_panel.current_floor = 1;
            % app.inside_panel.direction = "Stationary";
            % app.inside_panel.door_state = "Closed";
            % app.inside_panel.route = [0, 0, 0];
            
            app.CurrentFloor.Value = "1";
            app.Direction.Value = "Stationary";
            app.DoorState.Value = "Closed";
        end

        % Button pushed function: OpenDoor
        function OpenDoorButtonPushed(app, event)
            if app.inside_panel.getDirection() == "Stationary"
                app.inside_panel.openDoor();
            end
        end

        % Button pushed function: CloseDoor
        function CloseDoorButtonPushed(app, event)
            app.inside_panel.closeDoor();
        end

        % Value changed function: Floor3Check
        function Floor3CheckValueChanged(app, event)
            value = app.Floor3Check.Value;
            if value == true
                app.inside_panel.addDesFloor(3);
            else
                app.inside_panel.delDesFloor(3);
            end
        end

        % Value changed function: Floor2Check
        function Floor2CheckValueChanged(app, event)
            value = app.Floor2Check.Value;
            if value == true
                app.inside_panel.addDesFloor(2);
            else
                app.inside_panel.delDesFloor(2);
            end
        end

        % Value changed function: Floor1Check
        function Floor1CheckValueChanged(app, event)
            value = app.Floor1Check.Value;
            if value == true
                app.inside_panel.addDesFloor(1);
            else
                app.inside_panel.delDesFloor(1);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ElePanel = uipanel(app.UIFigure);
            app.ElePanel.Title = 'Ele2';
            app.CurrentFloorTextAreaLabel = uilabel(app.ElePanel);
            app.CurrentFloorTextAreaLabel.Text = 'Current Floor';
            app.CurrentFloor = uitextarea(app.ElePanel);
            app.CurrentFloor.Editable = 'off';
            app.DirectionTextAreaLabel = uilabel(app.ElePanel);
            app.DirectionTextAreaLabel.Text = 'Direction';
            app.Direction = uitextarea(app.ElePanel);
            app.Direction.Editable = 'off';
            app.Floor3Check = uicheckbox(app.ElePanel);
            app.Floor3Check.ValueChangedFcn = createCallbackFcn(app, @Floor3CheckValueChanged, true);
            app.Floor3Check.Text = {'Floor 3'; ''};
            app.Floor2Check = uicheckbox(app.ElePanel);
            app.Floor2Check.ValueChangedFcn = createCallbackFcn(app, @Floor2CheckValueChanged, true);
            app.Floor2Check.Text = 'Floor 2';
            app.Floor1Check = uicheckbox(app.ElePanel);
            app.Floor1Check.ValueChangedFcn = createCallbackFcn(app, @Floor1CheckValueChanged, true);
            app.Floor1Check.Enable = 'off';
            app.Floor1Check.Text = {'Floor 1'; ''};
            app.OpenDoor = uibutton(app.ElePanel, 'push');
            app.OpenDoor.ButtonPushedFcn = createCallbackFcn(app, @OpenDoorButtonPushed, true);
            app.OpenDoor.Text = 'Open Door';
            app.DoorStateTextAreaLabel = uilabel(app.ElePanel);
            app.DoorStateTextAreaLabel.Text = 'Door State';
            app.DoorState = uitextarea(app.ElePanel);
            app.DoorState.Editable = 'off';
            app.CloseDoor = uibutton(app.ElePanel, 'push');
            app.CloseDoor.ButtonPushedFcn = createCallbackFcn(app, @CloseDoorButtonPushed, true);
            app.CloseDoor.Text = 'Close Door';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = elevator2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end