classdef floor2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FloorPanel                matlab.ui.container.Panel
        Ele1FloorTextArea_2Label  matlab.ui.control.Label
        Ele1Floor                 matlab.ui.control.TextArea
        Ele1DirTextArea_2Label    matlab.ui.control.Label
        Ele1Dir                   matlab.ui.control.TextArea
        Ele2FloorTextArea_2Label  matlab.ui.control.Label
        Ele2Floor                 matlab.ui.control.TextArea
        Ele2DirTextArea_2Label    matlab.ui.control.Label
        Ele2Dir                   matlab.ui.control.TextArea
        UpButton                  matlab.ui.control.Button
        DownButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        outside_panel
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Ele1Floor.Value = "1";
            app.Ele1Dir.Value = "Stationary";
            app.Ele2Floor.Value = "1";
            app.Ele2Dir.Value = "Stationary";
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            app.outside_panel.callEle(2, "Up");
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            app.outside_panel.callEle(2, "Down");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FloorPanel = uipanel(app.UIFigure);
            app.FloorPanel.Title = 'Floor 2';
            app.Ele1FloorTextArea_2Label = uilabel(app.FloorPanel);
            app.Ele1FloorTextArea_2Label.Text = 'Ele1 Floor';
            app.Ele1Floor = uitextarea(app.FloorPanel);
            app.Ele1Floor.Editable = 'off';
            app.Ele1DirTextArea_2Label = uilabel(app.FloorPanel);
            app.Ele1DirTextArea_2Label.Text = 'Ele1 Dir';
            app.Ele1Dir = uitextarea(app.FloorPanel);
            app.Ele1Dir.Editable = 'off';
            app.Ele2FloorTextArea_2Label = uilabel(app.FloorPanel);
            app.Ele2FloorTextArea_2Label.Text = 'Ele2 Floor';
            app.Ele2Floor = uitextarea(app.FloorPanel);
            app.Ele2Floor.Editable = 'off';
            app.Ele2DirTextArea_2Label = uilabel(app.FloorPanel);
            app.Ele2DirTextArea_2Label.Text = 'Ele2 Dir';
            app.Ele2Dir = uitextarea(app.FloorPanel);
            app.Ele2Dir.Editable = 'off';
            app.UpButton = uibutton(app.FloorPanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.FloorPanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = floor2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end